classdef user < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        InformationPanel        matlab.ui.container.Panel
        Label                   matlab.ui.control.Label
        MediumListPanel         matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        UITable                 matlab.ui.control.Table
        addmediumDropDown       matlab.ui.control.DropDown
        addmediumDropDownLabel  matlab.ui.control.Label
        CreateButton            matlab.ui.control.Button
    end

    %% 額外屬性 (Private)
    properties (Access = private)
        DialogApp_Create % Dialog box app 
        DialogApp_Modify  
    end
    
    %% 額外屬性 (Public) (For store data)
    properties (Access = public)
        M MediumList
        % empty table
        % column: "Name", "Type", "Order", "up", "down", "Modify"
        T = cell2table(cell(0,6), 'VariableNames', {'Name','Type',...
            'Order','up','down','Modify'})
    end
    
    %% public method
    methods (Access = public)

        %% 從 dialog 呼叫此方法
        function update_from_create(app,object)
            disp("[info]: 已創建一個名為 " + object.name + " 的 " + object.type)
            app.M.add(object)   % 新增 Medium
            app.M.set_order     % 自動填入順序 (根據建立順序)
            app.update_T        % 更新 T 屬性
            app.update_table    % 更新顯示的 table
        end

        %% 
        function update_from_modify(app,index,object)
            disp("[info]: 已修改一個名為 " + object.name + " 的 " + object.type)
            app.M.replace(index,object)     % 更新 Medium
            app.update_T                    % 更新 T 屬性
            app.update_table                % 更新顯示的 table
        end

        %% update Table
        function update_T(app)
            num = length(app.M.list);
            name = cell(num,1);
            type = cell(num,1);
            order = cell(num,1);
            up = cell(num,1); down = cell(num,1); modify = cell(num,1);
            for ii = 1 : num
                name{ii} = char(app.M.list(ii).name);
                type{ii} = char(app.M.list(ii).type);
                order{ii} = num2str(app.M.list(ii).order);
            end
            app.T = table(name,type,order,up,down,modify);
        end
    end
    
    %% private method
    methods (Access = private)
        %% 更新 medium list table
        function update_table(app)
            app.UITable.Data = table2cell(app.T);
        end

        %% 更新 info panel (by 選擇 table 不同 cell)
        function update_info(app,str)
            app.Label.Text = str;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add pathtool
            addpath(fullfile(cd,"window"));
            addpath(fullfile(cd,"function"));
            addpath(fullfile(cd,"class"));

            % M
            app.M = MediumList;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.CreateButton.Enable = "off";
    
            % Call dialog box with input values
            switch app.addmediumDropDown.Value
                case "Cube"
                    disp("[info]: 創建 Cube...")
                    app.DialogApp_Create = create_cube(app);
                case "Lens"

                case "GP"
                    disp("[info]: 創建 GP...")
                    app.DialogApp_Create = create_gp(app);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           % Delete all apps
           delete(app.DialogApp_Create)
           delete(app.DialogApp_Modify)
           delete(app)    
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            % 說明:
            % select any row: 顯示該 row medium 資訊
            % select column "up" or "down": 改變 medium 順序
            % select column "modify": 修改 medium 參數

            %% show information
            if ~isempty(indices)
                if size(indices,1) == 1 % 多項選擇時不顯示
                    disp("[info]: Show information of medium @ row "+ indices(1))
                    % useful property extract
                    str = info_extract(app.M.list(indices(1)));
                    update_info(app, str) % 顯示該 Medium 資訊
                end
            end

            %% order setup
            if ~isempty(indices)
                switch indices(2)
                    case 4  % 該 row 往上移一格順序
                        if indices(1) ~= 1
                            app.M.list(indices(1)).set_order(indices(1)-1);
                            app.M.list(indices(1)-1).set_order(indices(1));
                            temp = app.M.list(indices(1)-1);
                            app.M.list(indices(1)-1) = app.M.list(indices(1));
                            app.M.list(indices(1)) = temp;
                            app.update_T        % 更新 T 屬性
                            app.update_table    % 更新顯示的 table
                            disp("[info]: 將 medium row "+ string(indices(1))+" 移至 row "+ string(indices(1)-1));
                        end
                        % 取消選取
                        app.UITable.Selection = [];
                    case 5  % 該 row 往下移一格順序
                        total_number = length(app.M.list);
                        if indices(1) ~= total_number
                            app.M.list(indices(1)).set_order(indices(1)+1);
                            app.M.list(indices(1)+1).set_order(indices(1));
                            temp = app.M.list(indices(1)+1);
                            app.M.list(indices(1)+1) = app.M.list(indices(1));
                            app.M.list(indices(1)) = temp;
                            app.update_T        % 更新 T 屬性
                            app.update_table    % 更新顯示的 table
                            disp("[info]: 將 medium row "+ string(indices(1))+" 移至 row "+ string(indices(1)-1));
                        end
                        % 取消選取W
                        app.UITable.Selection = [];
                    otherwise   % 不動作
                end
            end

            %% modification
            if ~isempty(indices)
                if indices(2) == 6
                    index = indices(1);
                    % Disable Plot Options button while dialog is open
                    app.UITable.Enable = "off";
            
                    % Call dialog box with input values
                    disp("[info]: 修改名為 " + app.M.list(index).name + " 的 " +...
                        app.M.list(index).type + " ...");
                    switch app.M.list(index).type
                        case "Cube"
                            app.DialogApp_Modify = modify_cube(app,index);
                        case "GP"
                            app.DialogApp_Modify = modify_gp(app,index);
                        case "Lens"
                    end
                    
                    % 取消選取
                    app.UITable.Selection = [];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MediumListPanel = uipanel(app.GridLayout);
            app.MediumListPanel.Title = 'Medium List';
            app.GridLayout2 = uigridlayout(app.MediumListPanel);
            app.CreateButton = uibutton(app.GridLayout2, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.addmediumDropDownLabel = uilabel(app.GridLayout2);
            app.addmediumDropDownLabel.Text = 'add medium';
            app.addmediumDropDown = uidropdown(app.GridLayout2);
            app.addmediumDropDown.Items = {'Cube', 'Lens', 'GP'};
            app.addmediumDropDown.Value = 'Cube';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Name'; 'Type'; 'Order'; 'up'; 'down'; 'Modify'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.InformationPanel = uipanel(app.GridLayout);
            app.InformationPanel.Title = 'Information';
            app.Label = uilabel(app.InformationPanel);
            app.Label.Interpreter = 'html';
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end